classdef gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        SpecifySpideryMeshButtonGroup  matlab.ui.container.ButtonGroup
        OnOffCheckBox                  matlab.ui.control.CheckBox
        GridLayout2                    matlab.ui.container.GridLayout
        EditField4                     matlab.ui.control.EditField
        EditField4Label                matlab.ui.control.Label
        EditField3                     matlab.ui.control.EditField
        EditField3Label                matlab.ui.control.Label
        EditField2                     matlab.ui.control.EditField
        EditField2Label                matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        EditFieldLabel                 matlab.ui.control.Label
        CurrentPositionEditField       matlab.ui.control.EditField
        OpenFileButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % flags

        spideryGrid_toggle   % specifies wether the grid should be displayed or not
        InputImage      % documentation of the Image

        % spider mesh
        OuterRect       % specify the four corners of outer rectangle
        % upleft-upright-downright-downleft
        InnerRect       %stores the values of the inner rectangle
        
         
        OuterFixies=[]     %points on the outer rectangle connected with  VP and inner rectangle verticies
        draw_inner_rect  %draw Object for rear Window
        VanishPoint         %draw Object for vanishing point
        Innerlines=[]    
        Outerlines=[]
        y_limit
        x_limit
    end
    
    % specify VanishPoint, InnerRect, OuterRect and RadialLines (function)
    methods (Access = private)


        function initialize_inner_rectangle_and_VP(app,input_image)
            input_image=rgb2gray(input_image);
            [pixels_y,pixels_x]=size(input_image);  %%y is number of rows and x is number of columns
            app.y_limit=pixels_y;
            app.x_limit=pixels_x;
            app.draw_inner_rect = drawrectangle(app.UIAxes,'Position',[pixels_x/4,pixels_y/4,pixels_x/2,pixels_y/2]);    %[x_min,y_min,width,heigth]
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'FaceSelectable'};
            ValueArray = {'IR', 0, 'white', 0, false};
            set(app.draw_inner_rect, NameArray, ValueArray);
            
            app.VanishPoint = drawpoint(app.UIAxes,'Position',[pixels_x/2,pixels_y/2]);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor'};
            ValueArray = {'VP', 0, 'white'};
            set(app.VanishPoint, NameArray, ValueArray);
            
            app.Innerlines=[];
            inner_points_temp=get_current_inner_rectangle_points(app);
            for l = 1:4
                 temp = [app.VanishPoint.Position; inner_points_temp(l,:)];
                 app.Innerlines(l) = drawline(app.UIAxes, 'Position', temp, 'InteractionsAllowed', 'none', 'Linewidth', 2.0);
            end
            
            outer_points=calc_outer_points_from_inner_points(app,app.VanishPoint.Position,inner_points_temp);
        
            for l = 1:4
                 temp = [outer_points(l,:); inner_points_temp(l,:)]
                 app.Outerlines(l) = drawline(app.UIAxes, 'Position', temp, 'InteractionsAllowed', 'none', 'Linewidth', 2.0);
             end
           
        end
        
        function projected_point=calc_projected_point(app,vanishing_point,point_from_rectangle)    %%function expects a vector with x and y coordinates 
            %%this function calculates the projected point on the outer
            %%rectangle coming from the inner lines going from the
            %%vanishing point to the corresponding inner rectangle point
            %%doing so, it solves 4 equations (4 intersecting lines) and
            %%uses the most fitting point
            
            %%calculate Vector from vanishing point to point_from_rectangle
            vector=point_from_rectangle-vanishing_point;
            %first equation:
            solutions=[];
            solutions=[solutions [inv([vector(1) -1; vector(2) 0])*(-vanishing_point)']];    
            solutions=[solutions [inv([vector(1) 0; vector(2) -1])*([app.x_limit-vanishing_point(1);-vanishing_point(2)])]];
            solutions=[solutions [inv([vector(1) -1; vector(2) 0])*([-vanishing_point(1);app.y_limit-vanishing_point(2)])]];
            solutions=[solutions [inv([vector(1) 0; vector(2) -1])*(-vanishing_point)']]
            m=0;
            for idx=1:4
                if solutions(1,idx)<0
                    continue
                end
                if mod(idx,2)==1    %contains x information
                   if solutions(2,idx)<0 && solutions(2,idx)>app.x_limit
                      continue 
                   end
                else       %contains y information
                    if solutions(2,idx)<0 && solutions(2,idx)>app.y_limit
                        continue
                    end
                end
               if solutions(1,idx)>m
                   m=solutions(1,idx)
                   solution=idx;
               end
            end
            
            if solution==1
                projected_point=[solutions(2,1) 0];
            elseif solution==2
                projected_point=[app.x_limit solutions(2,2)];
            elseif solution==3
                projected_point=[solutions(2,3) app.y_limit];
            else
                projected_point=[0 solutions(2,4)];
            end   
        end
        
        function outer_points=calc_outer_points_from_inner_points(app,vanishing_point,inner_points)
                %first calculate upper left point 
                outer_points=[];
                for idx=1:4
                    outer_points=[outer_points ;calc_projected_point(app,vanishing_point,inner_points(idx,:))]
                end         
        end
        
%         function vanishing_point=calc_vanishing_point_from_outer_movement(app)
%                        
%             
%         end

        function inner_rectangle_points=get_current_inner_rectangle_points(app)
                temp=get(app.draw_inner_rect,'Position');
                x_min=temp(1);
                y_min=temp(2);
                width=temp(3);
                heigth=temp(4);
                inner_rectangle_points=[x_min y_min ; x_min+width y_min; x_min+width y_min+heigth; x_min y_min+heigth];                
        end


         function specify_OuterRectangle(app)
            app.OuterRect = [[0,0]',[size(app.InputImage,2),0]',[size(app.InputImage,2),size(app.InputImage,1)]',[0,size(app.InputImage,1)]'];
            draw_outer_rect = drawrectangle(app.UIAxes, 'Position', [0,0,size(app.InputImage,2),size(app.InputImage,1)]);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'InteractionsAllowed'};
            ValueArray = {'OR', 0, 'white', 0, 'none',};
            set(draw_outer_rect, NameArray, ValueArray);

%             % preparation of radiallines based on outer_fixies
%             app.OuterFixies = zeros(2,app.radial_size*length(app.OuterRect));
%             n = 1;
%             for i = 1:app.radial_size
%                 for j = 1:length(app.OuterRect)
%                     app.OuterFixies(:, n) = [round(i*app.OuterRect(1,j)/app.radial_size); app.OuterRect(2,j)];
%                     app.OuterFixies(:, n+1) = [app.OuterRect(1,j); round(i*app.OuterRect(2,j)/app.radial_size)];
%                     n = n + 2;
%                 end
%             end
%             app.OuterFixies = unique(app.OuterFixies', 'rows')';
         end


        function specify_RadialLines(app, vp)
            if ~app.drawVP_flag || ~app.drawRW_flag
                return;
            end
            % plot the radial lines
            app.draw_radial = [];
            %%% need to be optimized
            for l = 1:length(app.OuterFixies)
                radial_line = [vp app.OuterFixies(:,l)]';
                app.draw_radial(l) = drawline(app.UIAxes, 'Position', radial_line, 'InteractionsAllowed', 'none', 'Linewidth', 0.5);
            end
            for m = 1:length(app.InnerRect)
                correspond_point = app.correspond_point_to_outer_rect(vp, app.InnerRect(:,m));
                radial_line = [vp correspond_point]';
                app.draw_radial(l+m) = drawline(app.UIAxes, 'Position', radial_line, 'InteractionsAllowed', 'none', 'Linewidth', 2);
            end
        end

        % project the radiallines of innerrect on the outerrect
        function CorrespondPoint = correspond_point_to_outer_rect(app, point, compared_point)
            % determine the zone of the point in comparison to the compared
            % point
            if point(1) == compared_point(1)
                if point(2) > compared_point(2)
                    CorrespondPoint = [point(1); 0];
                elseif point(2) < compared_point(2)
                    CorrespondPoint = [point(1); size(app.InputImage,2)];
                else
                    CorrespondPoint = point;
                end
                return;
            else
                k = (point(2)-compared_point(2))/(point(1)-compared_point(1));
                b = point(2)-k*point(1);
                if point(1) > compared_point(1)
                    phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                    theta_1 = atan((compared_point(2)-size(app.InputImage,1))/(compared_point(1)-0));
                    theta_2 = atan((compared_point(2)-0)/(compared_point(1)-0));
                    if phi < theta_1
                        % edge unten
                        CorrespondPoint = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                    elseif phi >= theta_1 && phi < theta_2
                        % edge links
                        CorrespondPoint = [0; b];
                    else
                        % edge oben
                        CorrespondPoint = [-b/k; 0];
                    end
                else
                    phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                    theta_1 = atan((compared_point(2)-0)/(compared_point(1)-size(app.InputImage,2)));
                    theta_2 = atan((compared_point(2)-size(app.InputImage,1))/(compared_point(1)-size(app.InputImage,2)));
                    if phi < theta_1
                        % edge oben
                        CorrespondPoint = [-b/k; 0];
                    elseif phi >= theta_1 && phi < theta_2
                        % edge rechts
                        CorrespondPoint = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                    else
                        % edge unten
                        CorrespondPoint = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'on');
            title(app.UIAxes,'Tour Into The Picture');
            app.EditField.Value="this is my debug field";

           
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [file,path,FilterIndex] = uigetfile('*.*','Select an Image File');
            %startupFcn(app);
            if FilterIndex == 0
                return;
            end
            app.InputImage = imread(fullfile(path,file));
            imshow(app.InputImage,'Parent',app.UIAxes);
            app.specify_OuterRectangle();
            app.initialize_inner_rectangle_and_VP(app.InputImage);
        end

        % Callback function: UIAxes, UIFigure
        function UIFigureWindowButtonMotion(app, event)
            set(app.CurrentPositionEditField,'Value',sprintf('%.2f, %.2f',app.UIAxes.CurrentPoint(1,1:2)))
            
            if ~isempty(app.Innerlines)
            inner_points_temp=get_current_inner_rectangle_points(app);
            
             for l = 1:4
                temp = [app.VanishPoint.Position; inner_points_temp(l,:)];
                 set(app.Innerlines(l),'Position',temp);
                 %= drawline(app.UIAxes, 'Position', temp, 'InteractionsAllowed', 'none', 'Linewidth', 2.0);
             end
            end
             
            if ~isempty(app.Outerlines)
                
            
            outer_points=calc_outer_points_from_inner_points(app,app.VanishPoint.Position,inner_points_temp);
            for l = 1:4
                 temp = [outer_points(l,:); inner_points_temp(l,:)];
                 set(app.Outerlines(l),'Position',temp);
                 %app.Outerlines(l) = drawline(app.UIAxes, 'Position', temp, 'InteractionsAllowed', 'none', 'Linewidth', 2.0);
             end
            end
              %x=get(app.draw_inner_rect,'Position')


%             if app.drawRW_flag
%                 inner_rect_pos = app.draw_inner_rect.Position;
%                 app.InnerRect = [[inner_rect_pos(1);inner_rect_pos(2)],...
%                     [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)],...
%                     [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)+inner_rect_pos(4)],...
%                     [inner_rect_pos(1);inner_rect_pos(2)+inner_rect_pos(4)]];
%             end
%             if app.ShowRadialLinesCheckBox.Value
%                 delete(app.draw_radial)
%                 app.specify_RadialLines(app.VanishPoint);
%             end
        end

        % Value changed function: OnOffCheckBox
        function OnOffCheckBoxValueChanged(app, event)
            value = app.OnOffCheckBox.Value;
            app.spideryGrid_toggle=value;

            if value==false
                %% close the grid plot so that it is not shown anymore
                %set(app.draw_inner_rect,''......
            else
                %%plot the grid with the previous parameters

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OpenFileButton = uibutton(app.GridLayout2, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Icon = 'FileIcon.png';
            app.OpenFileButton.Text = 'Open File';
            app.CurrentPositionEditField = uieditfield(app.GridLayout2, 'text');
            app.EditFieldLabel = uilabel(app.GridLayout2);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField2Label = uilabel(app.GridLayout2);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.GridLayout2, 'text');
            app.EditField3Label = uilabel(app.GridLayout2);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.GridLayout2, 'text');
            app.EditField4Label = uilabel(app.GridLayout2);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.GridLayout2, 'text');
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SpecifySpideryMeshButtonGroup = uibuttongroup(app.GridLayout3);
            app.SpecifySpideryMeshButtonGroup.Title = 'Specify Spidery Mesh';
            app.OnOffCheckBox = uicheckbox(app.SpecifySpideryMeshButtonGroup);
            app.OnOffCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnOffCheckBoxValueChanged, true);
            app.OnOffCheckBox.Text = 'On/Off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end